classdef memorymatters_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RememberPersonButton  matlab.ui.control.StateButton
        ClickbuttonifyouwanttorememberapersonLabel  matlab.ui.control.Label
        Switch                matlab.ui.control.Switch
        MemoryMattersLabel    matlab.ui.control.Label
        DeviceisONLamp        matlab.ui.control.Lamp
        DeviceisONLampLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        loaded_net % Description
        mypi
        cam
        b
        folder_names
        relation_names
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mypi, cam, b, folder_names, relation_names)
        loaded_data = load('trained_network.mat');
        app.loaded_net = loaded_data.newnet;
        app.mypi=mypi;
        app.cam = cam;
        app.b = b;
        app.folder_names = folder_names;
         app.relation_names = relation_names;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            photo_path ='C:\Users\razve\OneDrive\Desktop\S3 - ECTE351\Modified_Attempt4\photos';
            relation_path = 'C:\Users\razve\OneDrive\Desktop\S3 - ECTE351\Modified_Attempt4\relation';
            while true
                if strcmp(value, 'On')
                    app.DeviceisONLamp.Color = 'g';
                    % Call your function here with the appropriate arguments
                    gpiopin_read = 16;
                    status = readDigitalPin(app.mypi,gpiopin_read);
                    if(status == 1)||(app.RememberPersonButton.Value == true)
                        %ask user to enter name and create directory
                        prompt = {'Enter name of person:'};
                        dlgtitle = 'Person';
                        name = inputdlg(prompt,dlgtitle);
                        name_conv = strcat('C:\Users\razve\OneDrive\Desktop\S3 - ECTE351\Modified_Attempt4\photos\',char(name));
                        mkdir(name_conv);%create folder with person's name
                        crop_path = 'C:\Users\razve\OneDrive\Desktop\S3 - ECTE351\Modified_Attempt4\croppedfaces';
                        new2 = strcat(crop_path,'\',char(name));
                        mkdir(new2);
                        % create folder for defining relation to person
                        prompt1 = {'Enter relation to person: '};
                        dlgtitle1 = 'Relation';
                        rel = inputdlg(prompt1,dlgtitle1);
                        create_relation = strcat(relation_path,'\',char(rel));
                        mkdir(create_relation);

                        % start training the data with addition to the new person's profile
                        capture_face(app.cam,char(name))
                        msgbox('Images successfully captured');
                        % Check if the MAT file exists
                        mat_file = 'trained_network.mat';
                        if exist(mat_file, 'file') == 2
                            % Delete the MAT file
                            delete(mat_file);
                            disp('MAT file deleted, new file generation in process.');
                        else
                            disp('MAT file does not exist.');
                        end
                        done(photo_path);
                        loaded_data = load('trained_network.mat');
                        app.loaded_net = loaded_data.newnet;
                    else
                        recognize_faces(app.cam,app.loaded_net,app.b,app.folder_names,app.relation_names);
                    end
            elseif strcmp(value, 'Off')
                app.DeviceisONLamp.Color = 'r';
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeviceisONLampLabel = uilabel(app.UIFigure);
            app.DeviceisONLampLabel.Text = 'Device is ON';
            app.DeviceisONLamp = uilamp(app.UIFigure);
            app.MemoryMattersLabel = uilabel(app.UIFigure);
            app.MemoryMattersLabel.Text = 'Memory Matters';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ClickbuttonifyouwanttorememberapersonLabel = uilabel(app.UIFigure);
            app.ClickbuttonifyouwanttorememberapersonLabel.Text = 'Click button if you want to remember a person';
            app.RememberPersonButton = uibutton(app.UIFigure, 'state');
            app.RememberPersonButton.Text = 'Remember Person';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = memorymatters_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end